classdef MCAPA2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MCAPAUIFigure                   matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadTab                         matlab.ui.container.Tab
        TabGroup2                       matlab.ui.container.TabGroup
        Subject1Tab                     matlab.ui.container.Tab
        plotButton_1                    matlab.ui.control.Button
        Trial1Label_1                   matlab.ui.control.Label
        Trail2Label_1                   matlab.ui.control.Label
        Trail3Label_1                   matlab.ui.control.Label
        Label_1                         matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        loadButton_1                    matlab.ui.control.Button
        saveButton_1                    matlab.ui.control.Button
        tableButton_1                   matlab.ui.control.Button
        NotesTextArea_1                 matlab.ui.control.TextArea
        ThresholdValueEditFieldLabel    matlab.ui.control.Label
        ThresholdValueEditField_1       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel_2  matlab.ui.control.Label
        formatSwitch_1                  matlab.ui.control.Switch
        Subject2Tab                     matlab.ui.container.Tab
        Trial1Label_2                   matlab.ui.control.Label
        Trail2Label_2                   matlab.ui.control.Label
        Trail3Label_2                   matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        NotesTextArea_2                 matlab.ui.control.TextArea
        plotButton_2                    matlab.ui.control.Button
        loadButton_2                    matlab.ui.control.Button
        saveButton_2                    matlab.ui.control.Button
        tableButton_2                   matlab.ui.control.Button
        ThresholdValueEditField_2Label  matlab.ui.control.Label
        ThresholdValueEditField_2       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel_3  matlab.ui.control.Label
        formatSwitch_2                  matlab.ui.control.Switch
        Subject3Tab                     matlab.ui.container.Tab
        Trial1Label_3                   matlab.ui.control.Label
        Trail2Label_3                   matlab.ui.control.Label
        Trail3Label_3                   matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_8                         matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        NotesTextArea_3                 matlab.ui.control.TextArea
        plotButton_3                    matlab.ui.control.Button
        loadButton_3                    matlab.ui.control.Button
        saveButton_3                    matlab.ui.control.Button
        tableButton_3                   matlab.ui.control.Button
        ThresholdValueEditField_3Label  matlab.ui.control.Label
        ThresholdValueEditField_3       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel_4  matlab.ui.control.Label
        formatSwitch_3                  matlab.ui.control.Switch
        Subject4Tab                     matlab.ui.container.Tab
        Trial1Label_4                   matlab.ui.control.Label
        Trail2Label_4                   matlab.ui.control.Label
        Trail3Label_4                   matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        NotesTextArea_4                 matlab.ui.control.TextArea
        plotButton_4                    matlab.ui.control.Button
        loadButton_4                    matlab.ui.control.Button
        saveButton_4                    matlab.ui.control.Button
        tableButton_4                   matlab.ui.control.Button
        ThresholdValueEditField_4Label  matlab.ui.control.Label
        ThresholdValueEditField_4       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel_5  matlab.ui.control.Label
        formatSwitch_4                  matlab.ui.control.Switch
        Subject5Tab                     matlab.ui.container.Tab
        Trial1Label_5                   matlab.ui.control.Label
        Trail2Label_5                   matlab.ui.control.Label
        Trail3Label_5                   matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        NotesTextArea_5                 matlab.ui.control.TextArea
        plotButton_5                    matlab.ui.control.Button
        loadButton_5                    matlab.ui.control.Button
        saveButton_5                    matlab.ui.control.Button
        tableButton_5                   matlab.ui.control.Button
        ThresholdValueEditField_5Label  matlab.ui.control.Label
        ThresholdValueEditField_5       matlab.ui.control.NumericEditField
        ThresholdValueEditFieldLabel_6  matlab.ui.control.Label
        formatSwitch_5                  matlab.ui.control.Switch
        OldStatsTab                     matlab.ui.container.Tab
        OldStats1Label                  matlab.ui.control.Label
        Label_16                        matlab.ui.control.Label
        OldStats2Label                  matlab.ui.control.Label
        Label_17                        matlab.ui.control.Label
        OldStats3Label                  matlab.ui.control.Label
        Label_18                        matlab.ui.control.Label
        OldStats4Label                  matlab.ui.control.Label
        Label_19                        matlab.ui.control.Label
        OldStats5Label                  matlab.ui.control.Label
        Label_20                        matlab.ui.control.Label
        loadButton_6                    matlab.ui.control.Button
        loadButton_7                    matlab.ui.control.Button
        loadButton_8                    matlab.ui.control.Button
        loadButton_9                    matlab.ui.control.Button
        loadButton_10                   matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        TaskNameEditFieldLabel          matlab.ui.control.Label
        TaskNameEditField               matlab.ui.control.EditField
        JointstoplotDropDownLabel       matlab.ui.control.Label
        JointstoplotDropDown            matlab.ui.control.DropDown
        LeftHandedCheckBox              matlab.ui.control.CheckBox
        RightHandedCheckBox             matlab.ui.control.CheckBox
        ComparisonTab                   matlab.ui.container.Tab
        ComparetoOthersPanel            matlab.ui.container.Panel
        Subject1CheckBox                matlab.ui.control.CheckBox
        Subject2CheckBox                matlab.ui.control.CheckBox
        Subject3CheckBox                matlab.ui.control.CheckBox
        Subject4CheckBox                matlab.ui.control.CheckBox
        Subject5CheckBox                matlab.ui.control.CheckBox
        ControlGroupCheckBox_2          matlab.ui.control.CheckBox
        JointstoplotDropDown_2Label     matlab.ui.control.Label
        JointstoplotDropDown_2          matlab.ui.control.DropDown
        graphButton                     matlab.ui.control.Button
        TaskNameEditField_2Label        matlab.ui.control.Label
        TaskNameEditField_2             matlab.ui.control.EditField
        LeftHandedCheckBox_2            matlab.ui.control.CheckBox
        RightHandedCheckBox_2           matlab.ui.control.CheckBox
        DropDown                        matlab.ui.control.DropDown
        barButton                       matlab.ui.control.Button
        ComparetoSubjectsOldStatsPanel  matlab.ui.container.Panel
        SubjectDropDownLabel            matlab.ui.control.Label
        SubjectDropDown                 matlab.ui.control.DropDown
        OldStats1CheckBox               matlab.ui.control.CheckBox
        OldStats2CheckBox               matlab.ui.control.CheckBox
        OldStats3CheckBox               matlab.ui.control.CheckBox
        OldStats4CheckBox               matlab.ui.control.CheckBox
        OldStats5CheckBox               matlab.ui.control.CheckBox
        ControlGroupCheckBox            matlab.ui.control.CheckBox
        graphButton_2                   matlab.ui.control.Button
        JointstoplotDropDown_3Label     matlab.ui.control.Label
        JointstoplotDropDown_3          matlab.ui.control.DropDown
        CurrentStatsSwitchLabel         matlab.ui.control.Label
        CurrentStatsSwitch              matlab.ui.control.Switch
        TaskNameEditField_3Label        matlab.ui.control.Label
        TaskNameEditField_3             matlab.ui.control.EditField
        LeftHandedCheckBox_3            matlab.ui.control.CheckBox
        RightHandedCheckBox_3           matlab.ui.control.CheckBox
        DropDown_2                      matlab.ui.control.DropDown
        barButton_2                     matlab.ui.control.Button
        ControlGroupTab                 matlab.ui.container.Tab
        AddNewSubjectPanel              matlab.ui.container.Panel
        CGDataLabel                     matlab.ui.control.Label
        Label_21                        matlab.ui.control.Label
        loadButton_11                   matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        NameLabel                       matlab.ui.control.Label
        EditField_2                     matlab.ui.control.EditField
        RemoveSubjectPanel              matlab.ui.container.Panel
        SubjectLabel                    matlab.ui.control.Label
        RemoveButton                    matlab.ui.control.Button
        DropDown_5                      matlab.ui.control.DropDown
        Panel_3                         matlab.ui.container.Panel
        DropDown_3                      matlab.ui.control.DropDown
        TaskLabel                       matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        tableButton_6                   matlab.ui.control.Button
        Blank                           matlab.ui.container.Tree
        AddNewTaskPanel                 matlab.ui.container.Panel
        AddButton_2                     matlab.ui.control.Button
        EditField_3                     matlab.ui.control.EditField
        TaskNameLabel                   matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        RemoveTaskPanel                 matlab.ui.container.Panel
        RemoveButton_2                  matlab.ui.control.Button
        TaskNameLabel_2                 matlab.ui.control.Label
        DropDown_4                      matlab.ui.control.DropDown
        Table                           matlab.ui.container.Tab
        TabGroup3                       matlab.ui.container.TabGroup
        Subject1Tab_2                   matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        saveButton_6                    matlab.ui.control.Button
        Subject2Tab_2                   matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        saveButton_7                    matlab.ui.control.Button
        Subject3Tab_2                   matlab.ui.container.Tab
        UITable_3                       matlab.ui.control.Table
        saveButton_8                    matlab.ui.control.Button
        Subject4Tab_2                   matlab.ui.container.Tab
        UITable_4                       matlab.ui.control.Table
        saveButton_9                    matlab.ui.control.Button
        Subject5Tab_2                   matlab.ui.container.Tab
        UITable_5                       matlab.ui.control.Table
        saveButton_10                   matlab.ui.control.Button
        CGTab                           matlab.ui.container.Tab
        UITable_6                       matlab.ui.control.Table
        saveButton_11                   matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if exist("C:\MCAPA\",'dir') == 0
                mkdir("C:\MCAPA")
                mkdir("C:\MCAPA\temp")
                mkdir("C:\MCAPA\CGdata")
            end
            
            LIST = dir('C:\MCAPA\CGdata\*.mat');
            if ~isempty(LIST)
                for i = 1:length(LIST)
                    NAME(i) = sprintf("%s",erase(LIST(i).name,["CG",".mat"]));
                end
            else
                NAME = '';
            end
            app.DropDown.Items = ["--",NAME];
            app.DropDown.Value = "--";
            app.DropDown_2.Items = ["--",NAME];
            app.DropDown_2.Value = "--";
            app.DropDown_3.Items = ["--",NAME];
            app.DropDown_3.Value = "--";
            app.DropDown_4.Items = ["--",NAME];
            app.DropDown_4.Value = "--";
        end

        % Button pushed function: plotButton_1
        function plotButton_1Pushed(app, event)
            S1PLOTBUTTON
        end

        % Button pushed function: plotButton_2
        function plotButton_2Pushed(app, event)
            S2PLOTBUTTON
        end

        % Button pushed function: plotButton_3
        function plotButton_3Pushed(app, event)
            S3PLOTBUTTON
        end

        % Button pushed function: plotButton_4
        function plotButton_4Pushed(app, event)
            S4PLOTBUTTON
        end

        % Button pushed function: plotButton_5
        function plotButton_5Pushed(app, event)
            S5PLOTBUTTON
        end

        % Button pushed function: graphButton
        function graphButtonPushed(app, event)
            GRAPHBUTTON1
        end

        % Button pushed function: graphButton_2
        function graphButton_2Pushed(app, event)
            GRAPHBUTTON2
        end

        % Button pushed function: loadButton_6
        function loadButton_6Pushed(app, event)
            [name,path]=uigetfile('*.mat*');
            if name ~= 0
                load(strcat(path,name))
                O1T1=T1;
                O1T2=T2;
                O1T3=T3;
                O1FORMAT=FORMAT;
                O1N=erase(name,'.mat');
                save('C:\MCAPA\temp\O1','O1T1','O1T2','O1T3','O1FORMAT','O1N')
                app.Label_16.Text=O1N;
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_7
        function loadButton_7Pushed(app, event)
            [name,path]=uigetfile('*.mat*');
            if name ~= 0
                load(strcat(path,name))
                O2T1=T1;
                O2T2=T2;
                O2T3=T3;
                O2FORMAT=FORMAT;
                O2N=erase(name,'.mat');
                save('C:\MCAPA\temp\O2','O2T1','O2T2','O2T3','O2FORMAT','O2N')
                app.Label_16.Text=O2N;
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_8
        function loadButton_8Pushed(app, event)
            [name,path]=uigetfile('*.mat*');
            if name ~= 0
                load(strcat(path,name))
                O3T1=T1;
                O3T2=T2;
                O3T3=T3;
                O3FORMAT=FORMAT;
                O3N=erase(name,'.mat');
                save('C:\MCAPA\temp\O3','O3T1','O3T2','O3T3','O3FORMAT','O3N')
                app.Label_16.Text=O3N;
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_9
        function loadButton_9Pushed(app, event)
            [name,path]=uigetfile('*.mat*');
            if name ~= 0
                load(strcat(path,name))
                O4T1=T1;
                O4T2=T2;
                O4T3=T3;
                O4FORMAT=FORMAT;
                O4N=erase(name,'.mat');
                save('C:\MCAPA\temp\O4','O4T1','O4T2','O4T3','O4FORMAT','O4N')
                app.Label_16.Text=O4N;
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_10
        function loadButton_10Pushed(app, event)
            [name,path]=uigetfile('*.mat*');
            if name ~= 0
                load(strcat(path,name))
                O5T1=T1;
                O5T2=T2;
                O5T3=T3;
                O5FORMAT=FORMAT;
                O5N=erase(name,'.mat');
                save('C:\MCAPA\temp\O5','O5T1','O5T2','O5T3','O5FORMAT','O5N')
                app.Label_16.Text=O5N;
                msgbox('Loaded')
            end
        end

        % Button pushed function: tableButton_1
        function tableButton_1Pushed(app, event)
            load('C:\MCAPA\temp\S1')
            Task=app.TaskNameEditField.Value;
            Left=app.LeftHandedCheckBox.Value;
            Data=individualTable(S1T1,S1T2,S1T3,Left,Task,S1FORMAT);
            app.UITable.ColumnName=[];
            app.UITable.RowName = [];
            app.UITable.Data=Data;
            app.TabGroup.SelectedTab=app.Table;
            app.TabGroup3.SelectedTab=app.Subject1Tab_2;
        end

        % Button pushed function: tableButton_2
        function tableButton_2Pushed(app, event)
            load('C:\MCAPA\temp\S2')
            Task=app.TaskNameEditField.Value;
            Left=app.LeftHandedCheckBox.Value;
            Data=individualTable(S2T1,S2T2,S2T3,Left,Task,S2FORMAT);
            app.UITable_2.ColumnName=[];
            app.UITable_2.RowName = [];
            app.UITable_2.Data=Data;
            app.TabGroup.SelectedTab=app.Table;
            app.TabGroup3.SelectedTab=app.Subject2Tab_2;
        end

        % Button pushed function: tableButton_3
        function tableButton_3Pushed(app, event)
            load('C:\MCAPA\temp\S3')
            Task=app.TaskNameEditField.Value;
            Left=app.LeftHandedCheckBox.Value;
            Data=individualTable(S3T1,S3T2,S3T3,Left,Task,S3FORMAT);
            app.UITable_3.ColumnName=[];
            app.UITable_3.RowName = [];
            app.UITable_3.Data=Data;
            app.TabGroup.SelectedTab=app.Table;
            app.TabGroup3.SelectedTab=app.Subject3Tab_2;
        end

        % Button pushed function: tableButton_4
        function tableButton_4Pushed(app, event)
            load('C:\MCAPA\temp\S4')
            Task=app.TaskNameEditField.Value;
            Left=app.LeftHandedCheckBox.Value;
            Data=individualTable(S4T1,S4T2,S4T3,Left,Task,S4FORMAT);
            app.UITable_4.ColumnName=[];
            app.UITable_4.RowName = [];
            app.UITable_4.Data=Data;
            app.TabGroup.SelectedTab=app.Table;
            app.TabGroup3.SelectedTab=app.Subject4Tab_2;
        end

        % Button pushed function: tableButton_5
        function tableButton_5Pushed(app, event)
            load('C:\MCAPA\temp\S5')
            Task=app.TaskNameEditField.Value;
            Left=app.LeftHandedCheckBox.Value;
            Data=individualTable(S5T1,S5T2,S5T3,Left,Task,S5FORMAT);
            app.UITable_5.ColumnName=[];
            app.UITable_5.RowName = [];
            app.UITable_5.Data=Data;
            app.TabGroup.SelectedTab=app.Table;
            app.TabGroup3.SelectedTab=app.Subject5Tab_2;
        end

        % Button pushed function: saveButton_6
        function saveButton_6Pushed(app, event)
            [file,path]=uiputfile('*.xlsx');
            name=fullfile(path,file);
            xlswrite(name,app.UITable.Data)
            msgbox('Saved')
        end

        % Button pushed function: saveButton_7
        function saveButton_7Pushed(app, event)
            [file,path]=uiputfile('*.xlsx');
            name=fullfile(path,file);
            xlswrite(name,app.UITable_2.Data)
            msgbox('Saved')
        end

        % Button pushed function: saveButton_8
        function saveButton_8Pushed(app, event)
            [file,path]=uiputfile('*.xlsx');
            name=fullfile(path,file);
            xlswrite(name,app.UITable_3.Data)
            msgbox('Saved')
        end

        % Button pushed function: saveButton_9
        function saveButton_9Pushed(app, event)
            [file,path]=uiputfile('*.xlsx');
            name=fullfile(path,file);
            xlswrite(name,app.UITable_4.Data)
            msgbox('Saved')
        end

        % Button pushed function: saveButton_10
        function saveButton_10Pushed(app, event)
            [file,path]=uiputfile('*.xlsx');
            name=fullfile(path,file);
            xlswrite(name,app.UITable_5.Data)
            msgbox('Saved')
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            load('C:\MCAPA\temp\CG')
            TYPE=app.DropDown_3.Value;
            NAME=app.EditField_2.Value;
            Sub=CGaddSubject(TYPE,NAME,CGT1,CGT2,CGT3,CGFORMAT);
            Tree = uitree(app.Panel_3);
            Tree.Position = [12 13 239 424];
            for i=1:length(Sub)
                ANGLE.(strcat('node',num2str(i)))=uitreenode(Tree);
                ANGLE.(strcat('node',num2str(i))).Text=Sub{i};
            end
            app.DropDown_5.Items = Sub;
        end

        % Button pushed function: loadButton_11
        function loadButton_11Pushed(app, event)
            [name,path]=uigetfile('*.mat*');
            if name ~= 0
                load(fullfile(path,name))
                CGT1=T1;
                CGT2=T2;
                CGT3=T3;
                CGFORMAT=FORMAT;
                CGN=erase(name,'.mat');
                save('C:\MCAPA\temp\CG','CGT1','CGT2','CGT3','CGFORMAT')
                app.Label_21.Text=CGN;
                app.EditField_2.Value=CGN;
                msgbox('Loaded')
            end
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            TYPE=app.DropDown_3.Value;
            NAME=app.DropDown_5.Value;
            Sub=CGrmSubject(TYPE,NAME);
            Tree = uitree(app.Panel_3);
            Tree.Position = [12 13 239 424];
            for i=1:length(Sub)
                ANGLE.(strcat('node',num2str(i)))=uitreenode(Tree);
                ANGLE.(strcat('node',num2str(i))).Text=Sub{i};
            end
            app.DropDown_5.Items = Sub;
        end

        % Value changed function: DropDown_3
        function DropDown_3ValueChanged(app, event)
            value = app.DropDown_3.Value;
            load(strcat('C:\MCAPA\CGdata\CG',value))
            Tree = uitree(app.Panel_3);
            Tree.Position = [12 13 239 424];
            for i=1:length(Sub)
                ANGLE.(strcat('node',num2str(i)))=uitreenode(Tree);
                ANGLE.(strcat('node',num2str(i))).Text=Sub{i};
            end
            app.DropDown_5.Items = Sub;
        end

        % Button pushed function: tableButton_6
        function tableButton_6Pushed(app, event)
            Task=app.DropDown_3.Value;
            Data=CGTable(Task,app.Switch.Value);
            app.UITable_6.ColumnName=[];
            app.UITable_6.RowName = [];
            app.UITable_6.Data=Data;
            app.TabGroup.SelectedTab=app.Table;
            app.TabGroup3.SelectedTab=app.CGTab;
        end

        % Button pushed function: saveButton_11
        function saveButton_11Pushed(app, event)
            [file,path]=uiputfile('*.xlsx');
            name=fullfile(path,file);
            xlswrite(name,app.UITable_6.Data)
            msgbox('Saved')
        end

        % Button pushed function: saveButton_1
        function saveButton_1Pushed(app, event)
            SAVESUBJECTBUTTON('S1',1,app)
        end

        % Button pushed function: saveButton_2
        function saveButton_2Pushed(app, event)
            SAVESUBJECTBUTTON('S2',2,app)
        end

        % Button pushed function: saveButton_3
        function saveButton_3Pushed(app, event)
            SAVESUBJECTBUTTON('S3',3,app)
        end

        % Button pushed function: saveButton_4
        function saveButton_4Pushed(app, event)
            SAVESUBJECTBUTTON('S4',4,app)
        end

        % Button pushed function: saveButton_5
        function saveButton_5Pushed(app, event)
            SAVESUBJECTBUTTON('S5',5,app)
        end

        % Button pushed function: loadButton_1
        function loadButton_1Pushed(app, event)
            [S1T1,S1T2,S1T3,S1FORMAT,app] = LOADTRIAL(app.ThresholdValueEditField_1.Value,1,app);
            if ~isempty(S1T1)
                save("C:\MCAPA\temp\S1.mat","S1T1","S1T2","S1T3","S1FORMAT")
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_2
        function loadButton_2Pushed(app, event)
            [S2T1,S2T2,S2T3,S2FORMAT,app] = LOADTRIAL(app.ThresholdValueEditField_2.Value,2,app);
            if ~isempty(S2T1)
                save("C:\MCAPA\temp\S2.mat","S2T1","S2T2","S2T3","S2FORMAT")
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_3
        function loadButton_3Pushed(app, event)
            [S3T1,S3T2,S3T3,S3FORMAT,app] = LOADTRIAL(app.ThresholdValueEditField_3.Value,3,app);
            if ~isempty(S3T1)
                save("C:\MCAPA\temp\S3.mat","S3T1","S3T2","S3T3","S3FORMAT")
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_4
        function loadButton_4Pushed(app, event)
            [S4T1,S4T2,S4T3,S4FORMAT,app] = LOADTRIAL(app.ThresholdValueEditField_4.Value,4,app);
            if ~isempty(S4T1)
                save("C:\MCAPA\temp\S4.mat","S4T1","S4T2","S4T3","S4FORMAT")
                msgbox('Loaded')
            end
        end

        % Button pushed function: loadButton_5
        function loadButton_5Pushed(app, event)
            [S5T1,S5T2,S5T3,S5FORMAT,app] = LOADTRIAL(app.ThresholdValueEditField_5.Value,5,app);
            if ~isempty(S5T1)
                save("C:\MCAPA\temp\S5.mat","S5T1","S5T2","S5T3","S5FORMAT")
                msgbox('Loaded')
            end
        end

        % Button pushed function: AddButton_2
        function AddButton_2Pushed(app, event)
            Sub = "";
            save(strcat('C:\MCAPA\CGdata\CG',app.EditField_3.Value,'_',app.Switch_2.Value),'Sub')
            LIST = dir('C:\MCAPA\CGdata\*.mat');
            for i = 1:length(LIST)
                NAME(i) = sprintf("%s",erase(LIST(i).name,["CG",".mat"]));
            end
            app.DropDown.Items = ["--",NAME];
            app.DropDown.Value = "--";
            app.DropDown_2.Items = ["--",NAME];
            app.DropDown_2.Value = "--";
            app.DropDown_3.Items = ["--",NAME];
            app.DropDown_3.Value = "--";
            app.DropDown_4.Items = ["--",NAME];
            app.DropDown_4.Value = "--";
            Tree = uitree(app.Panel_3);
            Tree.Position = [12 13 239 424];
            for i=1:length(Sub)
                ANGLE.(strcat('node',num2str(i)))=uitreenode(Tree);
                ANGLE.(strcat('node',num2str(i))).Text=Sub{i};
            end
            app.DropDown_5.Items = Sub;
        end

        % Button pushed function: RemoveButton_2
        function RemoveButton_2Pushed(app, event)
            delete(strcat('C:\MCAPA\CGdata\CG',app.DropDown_4.Value,'.mat'))
            LIST = dir('C:\MCAPA\CGdata\*.mat');
            if ~isempty(LIST)
                for i = 1:length(LIST)
                    NAME(i) = sprintf("%s",erase(LIST(i).name,["CG",".mat"]));
                end
            else
                NAME = '';
            end
            app.DropDown.Items = ["--",NAME];
            app.DropDown.Value = "--";
            app.DropDown_2.Items = ["--",NAME];
            app.DropDown_2.Value = "--";
            app.DropDown_3.Items = ["--",NAME];
            app.DropDown_3.Value = "--";
            app.DropDown_4.Items = ["--",NAME];
            app.DropDown_4.Value = "--";
            Sub = '';
            Tree = uitree(app.Panel_3);
            Tree.Position = [12 13 239 424];
            for i=1:length(Sub)
                ANGLE.(strcat('node',num2str(i)))=uitreenode(Tree);
                ANGLE.(strcat('node',num2str(i))).Text=Sub{i};
            end
            app.DropDown_5.Items = Sub;
        end

        % Button pushed function: barButton
        function barButtonPushed(app, event)
            BARBUTTON1
        end

        % Button pushed function: barButton_2
        function barButton_2Pushed(app, event)
            BARBUTTON2
        end

        % Close request function: MCAPAUIFigure
        function MCAPAUIFigureCloseRequest(app, event)
            answer = questdlg('Would you like close the app?', ...
            	'?', ...
            	'Yes','No','No');
            % Handle response
            switch answer
                case 'Yes'
                    delete(app)
                case 'No'
                    return
            end

                
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MCAPAUIFigure = uifigure;
            app.MCAPAUIFigure.Name = 'MCAPA';
            app.MCAPAUIFigure.CloseRequestFcn = createCallbackFcn(app, @MCAPAUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MCAPAUIFigure);
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.TabGroup2 = uitabgroup(app.LoadTab);
            app.Subject1Tab = uitab(app.TabGroup2);
            app.Subject1Tab.Title = 'Subject-1';
            app.plotButton_1 = uibutton(app.Subject1Tab, 'push');
            app.plotButton_1.ButtonPushedFcn = createCallbackFcn(app, @plotButton_1Pushed, true);
            app.plotButton_1.Text = 'plot';
            app.Trial1Label_1 = uilabel(app.Subject1Tab);
            app.Trial1Label_1.Text = 'Trial 1:';
            app.Trail2Label_1 = uilabel(app.Subject1Tab);
            app.Trail2Label_1.Text = 'Trail 2:';
            app.Trail3Label_1 = uilabel(app.Subject1Tab);
            app.Trail3Label_1.Text = 'Trail 3:';
            app.Label_1 = uilabel(app.Subject1Tab);
            app.Label_1.Text = '__';
            app.Label_2 = uilabel(app.Subject1Tab);
            app.Label_2.Text = '__';
            app.Label_3 = uilabel(app.Subject1Tab);
            app.Label_3.Text = '__';
            app.loadButton_1 = uibutton(app.Subject1Tab, 'push');
            app.loadButton_1.ButtonPushedFcn = createCallbackFcn(app, @loadButton_1Pushed, true);
            app.loadButton_1.Text = 'load';
            app.saveButton_1 = uibutton(app.Subject1Tab, 'push');
            app.saveButton_1.ButtonPushedFcn = createCallbackFcn(app, @saveButton_1Pushed, true);
            app.saveButton_1.Text = 'save';
            app.tableButton_1 = uibutton(app.Subject1Tab, 'push');
            app.tableButton_1.ButtonPushedFcn = createCallbackFcn(app, @tableButton_1Pushed, true);
            app.tableButton_1.Text = 'table';
            app.NotesTextArea_1 = uitextarea(app.Subject1Tab);
            app.NotesTextArea_1.Value = {'Anonymised Patient/Control ID:'; ''; 'Gender (Male/Female/Other/Prefer not to say):'; ''; 'Date of Birth (DD/MM/YYYY):'; ''; 'Dominant Arm (Right/Left):'; ''; 'Amputated Side (Right/Left):'; ''; 'Level of Amputation (Above-elbow/Below-elbow):'; ''; 'Prosthetic Type (Passive/Body-powered/ Myoelectric/Externally-powered/Activity-specific):'; ''; 'Terminal Device Type (Hook-shaped/Anthropomorphic):'; ''; 'Gripper type (Voluntary-open'; 'Voluntary-close):'; ''; 'Height (cm):'; ''; 'Weight (kg):'};
            app.ThresholdValueEditFieldLabel = uilabel(app.Subject1Tab);
            app.ThresholdValueEditFieldLabel.Text = 'Threshold Value:';
            app.ThresholdValueEditField_1 = uieditfield(app.Subject1Tab, 'numeric');
            app.ThresholdValueEditField_1.Value = 10;
            app.ThresholdValueEditFieldLabel_2 = uilabel(app.Subject1Tab);
            app.ThresholdValueEditFieldLabel_2.Text = 'mm';
            app.formatSwitch_1 = uiswitch(app.Subject1Tab, 'slider');
            app.formatSwitch_1.Items = {'.c3d', '.mvnx'};
            app.formatSwitch_1.Value = '.mvnx';
            app.Subject2Tab = uitab(app.TabGroup2);
            app.Subject2Tab.Title = 'Subject-2';
            app.Trial1Label_2 = uilabel(app.Subject2Tab);
            app.Trial1Label_2.Text = 'Trial 1:';
            app.Trail2Label_2 = uilabel(app.Subject2Tab);
            app.Trail2Label_2.Text = 'Trail 2:';
            app.Trail3Label_2 = uilabel(app.Subject2Tab);
            app.Trail3Label_2.Text = 'Trail 3:';
            app.Label_4 = uilabel(app.Subject2Tab);
            app.Label_4.Text = '__';
            app.Label_5 = uilabel(app.Subject2Tab);
            app.Label_5.Text = '__';
            app.Label_6 = uilabel(app.Subject2Tab);
            app.Label_6.Text = '__';
            app.NotesTextArea_2 = uitextarea(app.Subject2Tab);
            app.NotesTextArea_2.Value = {'Anonymised Patient/Control ID:'; ''; 'Gender (Male/Female/Other/Prefer not to say):'; ''; 'Date of Birth (DD/MM/YYYY):'; ''; 'Dominant Arm (Right/Left):'; ''; 'Amputated Side (Right/Left):'; ''; 'Level of Amputation (Above-elbow/Below-elbow):'; ''; 'Prosthetic Type (Passive/Body-powered/ Myoelectric/Externally-powered/Activity-specific):'; ''; 'Terminal Device Type (Hook-shaped/Anthropomorphic):'; ''; 'Gripper type (Voluntary-open'; 'Voluntary-close):'; ''; 'Height (cm):'; ''; 'Weight (kg):'};
            app.plotButton_2 = uibutton(app.Subject2Tab, 'push');
            app.plotButton_2.ButtonPushedFcn = createCallbackFcn(app, @plotButton_2Pushed, true);
            app.plotButton_2.Text = 'plot';
            app.loadButton_2 = uibutton(app.Subject2Tab, 'push');
            app.loadButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadButton_2Pushed, true);
            app.loadButton_2.Text = 'load';
            app.saveButton_2 = uibutton(app.Subject2Tab, 'push');
            app.saveButton_2.ButtonPushedFcn = createCallbackFcn(app, @saveButton_2Pushed, true);
            app.saveButton_2.Text = 'save';
            app.tableButton_2 = uibutton(app.Subject2Tab, 'push');
            app.tableButton_2.ButtonPushedFcn = createCallbackFcn(app, @tableButton_2Pushed, true);
            app.tableButton_2.Text = 'table';
            app.ThresholdValueEditField_2Label = uilabel(app.Subject2Tab);
            app.ThresholdValueEditField_2Label.Text = 'Threshold Value:';
            app.ThresholdValueEditField_2 = uieditfield(app.Subject2Tab, 'numeric');
            app.ThresholdValueEditField_2.Value = 10;
            app.ThresholdValueEditFieldLabel_3 = uilabel(app.Subject2Tab);
            app.ThresholdValueEditFieldLabel_3.Text = 'mm';
            app.formatSwitch_2 = uiswitch(app.Subject2Tab, 'slider');
            app.formatSwitch_2.Items = {'.c3d', '.mvnx'};
            app.formatSwitch_2.Value = '.mvnx';
            app.Subject3Tab = uitab(app.TabGroup2);
            app.Subject3Tab.Title = 'Subject-3';
            app.Trial1Label_3 = uilabel(app.Subject3Tab);
            app.Trial1Label_3.Text = 'Trial 1:';
            app.Trail2Label_3 = uilabel(app.Subject3Tab);
            app.Trail2Label_3.Text = 'Trail 2:';
            app.Trail3Label_3 = uilabel(app.Subject3Tab);
            app.Trail3Label_3.Text = 'Trail 3:';
            app.Label_7 = uilabel(app.Subject3Tab);
            app.Label_7.Text = '__';
            app.Label_8 = uilabel(app.Subject3Tab);
            app.Label_8.Text = '__';
            app.Label_9 = uilabel(app.Subject3Tab);
            app.Label_9.Text = '__';
            app.NotesTextArea_3 = uitextarea(app.Subject3Tab);
            app.NotesTextArea_3.Value = {'Anonymised Patient/Control ID:'; ''; 'Gender (Male/Female/Other/Prefer not to say):'; ''; 'Date of Birth (DD/MM/YYYY):'; ''; 'Dominant Arm (Right/Left):'; ''; 'Amputated Side (Right/Left):'; ''; 'Level of Amputation (Above-elbow/Below-elbow):'; ''; 'Prosthetic Type (Passive/Body-powered/ Myoelectric/Externally-powered/Activity-specific):'; ''; 'Terminal Device Type (Hook-shaped/Anthropomorphic):'; ''; 'Gripper type (Voluntary-open'; 'Voluntary-close):'; ''; 'Height (cm):'; ''; 'Weight (kg):'};
            app.plotButton_3 = uibutton(app.Subject3Tab, 'push');
            app.plotButton_3.ButtonPushedFcn = createCallbackFcn(app, @plotButton_3Pushed, true);
            app.plotButton_3.Text = 'plot';
            app.loadButton_3 = uibutton(app.Subject3Tab, 'push');
            app.loadButton_3.ButtonPushedFcn = createCallbackFcn(app, @loadButton_3Pushed, true);
            app.loadButton_3.Text = 'load';
            app.saveButton_3 = uibutton(app.Subject3Tab, 'push');
            app.saveButton_3.ButtonPushedFcn = createCallbackFcn(app, @saveButton_3Pushed, true);
            app.saveButton_3.Text = 'save';
            app.tableButton_3 = uibutton(app.Subject3Tab, 'push');
            app.tableButton_3.ButtonPushedFcn = createCallbackFcn(app, @tableButton_3Pushed, true);
            app.tableButton_3.Text = 'table';
            app.ThresholdValueEditField_3Label = uilabel(app.Subject3Tab);
            app.ThresholdValueEditField_3Label.Text = 'Threshold Value:';
            app.ThresholdValueEditField_3 = uieditfield(app.Subject3Tab, 'numeric');
            app.ThresholdValueEditField_3.Value = 10;
            app.ThresholdValueEditFieldLabel_4 = uilabel(app.Subject3Tab);
            app.ThresholdValueEditFieldLabel_4.Text = 'mm';
            app.formatSwitch_3 = uiswitch(app.Subject3Tab, 'slider');
            app.formatSwitch_3.Items = {'.c3d', '.mvnx'};
            app.formatSwitch_3.Value = '.mvnx';
            app.Subject4Tab = uitab(app.TabGroup2);
            app.Subject4Tab.Title = 'Subject-4';
            app.Trial1Label_4 = uilabel(app.Subject4Tab);
            app.Trial1Label_4.Text = 'Trial 1:';
            app.Trail2Label_4 = uilabel(app.Subject4Tab);
            app.Trail2Label_4.Text = 'Trail 2:';
            app.Trail3Label_4 = uilabel(app.Subject4Tab);
            app.Trail3Label_4.Text = 'Trail 3:';
            app.Label_10 = uilabel(app.Subject4Tab);
            app.Label_10.Text = '__';
            app.Label_11 = uilabel(app.Subject4Tab);
            app.Label_11.Text = '__';
            app.Label_12 = uilabel(app.Subject4Tab);
            app.Label_12.Text = '__';
            app.NotesTextArea_4 = uitextarea(app.Subject4Tab);
            app.NotesTextArea_4.Value = {'Anonymised Patient/Control ID:'; ''; 'Gender (Male/Female/Other/Prefer not to say):'; ''; 'Date of Birth (DD/MM/YYYY):'; ''; 'Dominant Arm (Right/Left):'; ''; 'Amputated Side (Right/Left):'; ''; 'Level of Amputation (Above-elbow/Below-elbow):'; ''; 'Prosthetic Type (Passive/Body-powered/ Myoelectric/Externally-powered/Activity-specific):'; ''; 'Terminal Device Type (Hook-shaped/Anthropomorphic):'; ''; 'Gripper type (Voluntary-open'; 'Voluntary-close):'; ''; 'Height (cm):'; ''; 'Weight (kg):'};
            app.plotButton_4 = uibutton(app.Subject4Tab, 'push');
            app.plotButton_4.ButtonPushedFcn = createCallbackFcn(app, @plotButton_4Pushed, true);
            app.plotButton_4.Text = 'plot';
            app.loadButton_4 = uibutton(app.Subject4Tab, 'push');
            app.loadButton_4.ButtonPushedFcn = createCallbackFcn(app, @loadButton_4Pushed, true);
            app.loadButton_4.Text = 'load';
            app.saveButton_4 = uibutton(app.Subject4Tab, 'push');
            app.saveButton_4.ButtonPushedFcn = createCallbackFcn(app, @saveButton_4Pushed, true);
            app.saveButton_4.Text = 'save';
            app.tableButton_4 = uibutton(app.Subject4Tab, 'push');
            app.tableButton_4.ButtonPushedFcn = createCallbackFcn(app, @tableButton_4Pushed, true);
            app.tableButton_4.Text = 'table';
            app.ThresholdValueEditField_4Label = uilabel(app.Subject4Tab);
            app.ThresholdValueEditField_4Label.Text = 'Threshold Value:';
            app.ThresholdValueEditField_4 = uieditfield(app.Subject4Tab, 'numeric');
            app.ThresholdValueEditField_4.Value = 10;
            app.ThresholdValueEditFieldLabel_5 = uilabel(app.Subject4Tab);
            app.ThresholdValueEditFieldLabel_5.Text = 'mm';
            app.formatSwitch_4 = uiswitch(app.Subject4Tab, 'slider');
            app.formatSwitch_4.Items = {'.c3d', '.mvnx'};
            app.formatSwitch_4.Value = '.mvnx';
            app.Subject5Tab = uitab(app.TabGroup2);
            app.Subject5Tab.Title = 'Subject-5';
            app.Trial1Label_5 = uilabel(app.Subject5Tab);
            app.Trial1Label_5.Text = 'Trial 1:';
            app.Trail2Label_5 = uilabel(app.Subject5Tab);
            app.Trail2Label_5.Text = 'Trail 2:';
            app.Trail3Label_5 = uilabel(app.Subject5Tab);
            app.Trail3Label_5.Text = 'Trail 3:';
            app.Label_13 = uilabel(app.Subject5Tab);
            app.Label_13.Text = '__';
            app.Label_14 = uilabel(app.Subject5Tab);
            app.Label_14.Text = '__';
            app.Label_15 = uilabel(app.Subject5Tab);
            app.Label_15.Text = '__';
            app.NotesTextArea_5 = uitextarea(app.Subject5Tab);
            app.NotesTextArea_5.Value = {'Anonymised Patient/Control ID:'; ''; 'Gender (Male/Female/Other/Prefer not to say):'; ''; 'Date of Birth (DD/MM/YYYY):'; ''; 'Dominant Arm (Right/Left):'; ''; 'Amputated Side (Right/Left):'; ''; 'Level of Amputation (Above-elbow/Below-elbow):'; ''; 'Prosthetic Type (Passive/Body-powered/ Myoelectric/Externally-powered/Activity-specific):'; ''; 'Terminal Device Type (Hook-shaped/Anthropomorphic):'; ''; 'Gripper type (Voluntary-open'; 'Voluntary-close):'; ''; 'Height (cm):'; ''; 'Weight (kg):'};
            app.plotButton_5 = uibutton(app.Subject5Tab, 'push');
            app.plotButton_5.ButtonPushedFcn = createCallbackFcn(app, @plotButton_5Pushed, true);
            app.plotButton_5.Text = 'plot';
            app.loadButton_5 = uibutton(app.Subject5Tab, 'push');
            app.loadButton_5.ButtonPushedFcn = createCallbackFcn(app, @loadButton_5Pushed, true);
            app.loadButton_5.Text = 'load';
            app.saveButton_5 = uibutton(app.Subject5Tab, 'push');
            app.saveButton_5.ButtonPushedFcn = createCallbackFcn(app, @saveButton_5Pushed, true);
            app.saveButton_5.Text = 'save';
            app.tableButton_5 = uibutton(app.Subject5Tab, 'push');
            app.tableButton_5.ButtonPushedFcn = createCallbackFcn(app, @tableButton_5Pushed, true);
            app.tableButton_5.Text = 'table';
            app.ThresholdValueEditField_5Label = uilabel(app.Subject5Tab);
            app.ThresholdValueEditField_5Label.Text = 'Threshold Value:';
            app.ThresholdValueEditField_5 = uieditfield(app.Subject5Tab, 'numeric');
            app.ThresholdValueEditField_5.Value = 10;
            app.ThresholdValueEditFieldLabel_6 = uilabel(app.Subject5Tab);
            app.ThresholdValueEditFieldLabel_6.Text = 'mm';
            app.formatSwitch_5 = uiswitch(app.Subject5Tab, 'slider');
            app.formatSwitch_5.Items = {'.c3d', '.mvnx'};
            app.formatSwitch_5.Value = '.mvnx';
            app.OldStatsTab = uitab(app.TabGroup2);
            app.OldStatsTab.Title = 'Old Stats';
            app.OldStats1Label = uilabel(app.OldStatsTab);
            app.OldStats1Label.Text = 'Old Stats 1:';
            app.Label_16 = uilabel(app.OldStatsTab);
            app.Label_16.Text = '__';
            app.OldStats2Label = uilabel(app.OldStatsTab);
            app.OldStats2Label.Text = 'Old Stats 2:';
            app.Label_17 = uilabel(app.OldStatsTab);
            app.Label_17.Text = '__';
            app.OldStats3Label = uilabel(app.OldStatsTab);
            app.OldStats3Label.Text = 'Old Stats 3:';
            app.Label_18 = uilabel(app.OldStatsTab);
            app.Label_18.Text = '__';
            app.OldStats4Label = uilabel(app.OldStatsTab);
            app.OldStats4Label.Text = 'Old Stats 4:';
            app.Label_19 = uilabel(app.OldStatsTab);
            app.Label_19.Text = '__';
            app.OldStats5Label = uilabel(app.OldStatsTab);
            app.OldStats5Label.Text = 'Old Stats 5:';
            app.Label_20 = uilabel(app.OldStatsTab);
            app.Label_20.Text = '__';
            app.loadButton_6 = uibutton(app.OldStatsTab, 'push');
            app.loadButton_6.ButtonPushedFcn = createCallbackFcn(app, @loadButton_6Pushed, true);
            app.loadButton_6.Text = 'load';
            app.loadButton_7 = uibutton(app.OldStatsTab, 'push');
            app.loadButton_7.ButtonPushedFcn = createCallbackFcn(app, @loadButton_7Pushed, true);
            app.loadButton_7.Text = 'load';
            app.loadButton_8 = uibutton(app.OldStatsTab, 'push');
            app.loadButton_8.ButtonPushedFcn = createCallbackFcn(app, @loadButton_8Pushed, true);
            app.loadButton_8.Text = 'load';
            app.loadButton_9 = uibutton(app.OldStatsTab, 'push');
            app.loadButton_9.ButtonPushedFcn = createCallbackFcn(app, @loadButton_9Pushed, true);
            app.loadButton_9.Text = 'load';
            app.loadButton_10 = uibutton(app.OldStatsTab, 'push');
            app.loadButton_10.ButtonPushedFcn = createCallbackFcn(app, @loadButton_10Pushed, true);
            app.loadButton_10.Text = 'load';
            app.Panel = uipanel(app.LoadTab);
            app.TaskNameEditFieldLabel = uilabel(app.Panel);
            app.TaskNameEditFieldLabel.Text = 'Task Name';
            app.TaskNameEditField = uieditfield(app.Panel, 'text');
            app.JointstoplotDropDownLabel = uilabel(app.Panel);
            app.JointstoplotDropDownLabel.Text = 'Joint(s) to plot';
            app.JointstoplotDropDown = uidropdown(app.Panel);
            app.JointstoplotDropDown.Items = {'All', 'Shoulder_all', 'Shoulder_flexion/extension(z)', 'Shoulder_abduction/adduction(x)', 'Shoulder_internal/external(y)', 'Elbow_all', 'Elbow_flexion/extension(z)', 'Elbow_pronation/supination(y)', 'Wrist_all', 'Wrist_flexion/extension(z)', 'Wrist_radial/ulnar deviation(x)'};
            app.JointstoplotDropDown.Value = 'All';
            app.LeftHandedCheckBox = uicheckbox(app.Panel);
            app.LeftHandedCheckBox.Text = 'Left Handed';
            app.RightHandedCheckBox = uicheckbox(app.Panel);
            app.RightHandedCheckBox.Text = 'Right Handed';
            app.ComparisonTab = uitab(app.TabGroup);
            app.ComparisonTab.Title = 'Comparison';
            app.ComparetoOthersPanel = uipanel(app.ComparisonTab);
            app.ComparetoOthersPanel.Title = 'Compare to Others';
            app.Subject1CheckBox = uicheckbox(app.ComparetoOthersPanel);
            app.Subject1CheckBox.Text = 'Subject-1';
            app.Subject2CheckBox = uicheckbox(app.ComparetoOthersPanel);
            app.Subject2CheckBox.Text = 'Subject-2';
            app.Subject3CheckBox = uicheckbox(app.ComparetoOthersPanel);
            app.Subject3CheckBox.Text = 'Subject-3';
            app.Subject4CheckBox = uicheckbox(app.ComparetoOthersPanel);
            app.Subject4CheckBox.Text = 'Subject-4';
            app.Subject5CheckBox = uicheckbox(app.ComparetoOthersPanel);
            app.Subject5CheckBox.Text = 'Subject-5';
            app.ControlGroupCheckBox_2 = uicheckbox(app.ComparetoOthersPanel);
            app.ControlGroupCheckBox_2.Text = 'Control Group';
            app.JointstoplotDropDown_2Label = uilabel(app.ComparetoOthersPanel);
            app.JointstoplotDropDown_2Label.Text = 'Joint(s) to plot';
            app.JointstoplotDropDown_2 = uidropdown(app.ComparetoOthersPanel);
            app.JointstoplotDropDown_2.Items = {'All', 'Shoulder_all', 'Shoulder_flexion/extension(z)', 'Shoulder_abduction/adduction(x)', 'Shoulder_internal/external(y)', 'Elbow_all', 'Elbow_flexion/extension(z)', 'Elbow_pronation/supination(y)', 'Wrist_all', 'Wrist_flexion/extension(z)', 'Wrist_radial/ulnar deviation(x)'};
            app.JointstoplotDropDown_2.Value = 'All';
            app.graphButton = uibutton(app.ComparetoOthersPanel, 'push');
            app.graphButton.ButtonPushedFcn = createCallbackFcn(app, @graphButtonPushed, true);
            app.graphButton.Text = 'graph';
            app.TaskNameEditField_2Label = uilabel(app.ComparetoOthersPanel);
            app.TaskNameEditField_2Label.Text = 'Task Name';
            app.TaskNameEditField_2 = uieditfield(app.ComparetoOthersPanel, 'text');
            app.LeftHandedCheckBox_2 = uicheckbox(app.ComparetoOthersPanel);
            app.LeftHandedCheckBox_2.Text = 'Left Handed';
            app.RightHandedCheckBox_2 = uicheckbox(app.ComparetoOthersPanel);
            app.RightHandedCheckBox_2.Text = 'Right Handed';
            app.DropDown = uidropdown(app.ComparetoOthersPanel);
            app.DropDown.Items = {'--'};
            app.DropDown.Value = '--';
            app.barButton = uibutton(app.ComparetoOthersPanel, 'push');
            app.barButton.ButtonPushedFcn = createCallbackFcn(app, @barButtonPushed, true);
            app.barButton.Text = 'bar';
            app.ComparetoSubjectsOldStatsPanel = uipanel(app.ComparisonTab);
            app.ComparetoSubjectsOldStatsPanel.Title = 'Compare to Subject''s Old Stats';
            app.SubjectDropDownLabel = uilabel(app.ComparetoSubjectsOldStatsPanel);
            app.SubjectDropDownLabel.Text = 'Subject';
            app.SubjectDropDown = uidropdown(app.ComparetoSubjectsOldStatsPanel);
            app.SubjectDropDown.Items = {'Subject-1', 'Subject-2', 'Subject-3', 'Subject-4', 'Subject-5'};
            app.SubjectDropDown.Value = 'Subject-1';
            app.OldStats1CheckBox = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.OldStats1CheckBox.Text = 'Old Stats-1';
            app.OldStats2CheckBox = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.OldStats2CheckBox.Text = 'Old Stats-2';
            app.OldStats3CheckBox = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.OldStats3CheckBox.Text = 'Old Stats-3';
            app.OldStats4CheckBox = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.OldStats4CheckBox.Text = 'Old Stats-4';
            app.OldStats5CheckBox = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.OldStats5CheckBox.Text = 'Old Stats-5';
            app.ControlGroupCheckBox = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.ControlGroupCheckBox.Text = 'Control Group';
            app.graphButton_2 = uibutton(app.ComparetoSubjectsOldStatsPanel, 'push');
            app.graphButton_2.ButtonPushedFcn = createCallbackFcn(app, @graphButton_2Pushed, true);
            app.graphButton_2.Text = 'graph';
            app.JointstoplotDropDown_3Label = uilabel(app.ComparetoSubjectsOldStatsPanel);
            app.JointstoplotDropDown_3Label.Text = 'Joint(s) to plot';
            app.JointstoplotDropDown_3 = uidropdown(app.ComparetoSubjectsOldStatsPanel);
            app.JointstoplotDropDown_3.Items = {'All', 'Shoulder_all', 'Shoulder_flexion/extension(z)', 'Shoulder_abduction/adduction(x)', 'Shoulder_internal/external(y)', 'Elbow_all', 'Elbow_flexion/extension(z)', 'Elbow_pronation/supination(y)', 'Wrist_all', 'Wrist_flexion/extension(z)', 'Wrist_radial/ulnar deviation(x)'};
            app.JointstoplotDropDown_3.Value = 'All';
            app.CurrentStatsSwitchLabel = uilabel(app.ComparetoSubjectsOldStatsPanel);
            app.CurrentStatsSwitchLabel.Text = 'Current Stats';
            app.CurrentStatsSwitch = uiswitch(app.ComparetoSubjectsOldStatsPanel, 'slider');
            app.CurrentStatsSwitch.Value = 'On';
            app.TaskNameEditField_3Label = uilabel(app.ComparetoSubjectsOldStatsPanel);
            app.TaskNameEditField_3Label.Text = 'Task Name';
            app.TaskNameEditField_3 = uieditfield(app.ComparetoSubjectsOldStatsPanel, 'text');
            app.LeftHandedCheckBox_3 = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.LeftHandedCheckBox_3.Text = 'Left Handed';
            app.RightHandedCheckBox_3 = uicheckbox(app.ComparetoSubjectsOldStatsPanel);
            app.RightHandedCheckBox_3.Text = 'Right Handed';
            app.DropDown_2 = uidropdown(app.ComparetoSubjectsOldStatsPanel);
            app.DropDown_2.Items = {'--'};
            app.DropDown_2.Value = '--';
            app.barButton_2 = uibutton(app.ComparetoSubjectsOldStatsPanel, 'push');
            app.barButton_2.ButtonPushedFcn = createCallbackFcn(app, @barButton_2Pushed, true);
            app.barButton_2.Text = 'bar';
            app.ControlGroupTab = uitab(app.TabGroup);
            app.ControlGroupTab.Title = 'Control Group';
            app.AddNewSubjectPanel = uipanel(app.ControlGroupTab);
            app.AddNewSubjectPanel.Title = 'Add New Subject';
            app.CGDataLabel = uilabel(app.AddNewSubjectPanel);
            app.CGDataLabel.Text = 'CG Data:';
            app.Label_21 = uilabel(app.AddNewSubjectPanel);
            app.Label_21.Text = '__';
            app.loadButton_11 = uibutton(app.AddNewSubjectPanel, 'push');
            app.loadButton_11.ButtonPushedFcn = createCallbackFcn(app, @loadButton_11Pushed, true);
            app.loadButton_11.Text = 'load';
            app.AddButton = uibutton(app.AddNewSubjectPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.NameLabel = uilabel(app.AddNewSubjectPanel);
            app.NameLabel.Text = 'Name:';
            app.EditField_2 = uieditfield(app.AddNewSubjectPanel, 'text');
            app.RemoveSubjectPanel = uipanel(app.ControlGroupTab);
            app.RemoveSubjectPanel.Title = 'Remove Subject';
            app.SubjectLabel = uilabel(app.RemoveSubjectPanel);
            app.SubjectLabel.Text = 'Subject:';
            app.RemoveButton = uibutton(app.RemoveSubjectPanel, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.DropDown_5 = uidropdown(app.RemoveSubjectPanel);
            app.DropDown_5.Items = {};
            app.DropDown_5.Value = {};
            app.Panel_3 = uipanel(app.ControlGroupTab);
            app.DropDown_3 = uidropdown(app.Panel_3);
            app.DropDown_3.Items = {'--'};
            app.DropDown_3.ValueChangedFcn = createCallbackFcn(app, @DropDown_3ValueChanged, true);
            app.DropDown_3.Value = '--';
            app.TaskLabel = uilabel(app.Panel_3);
            app.TaskLabel.Text = 'Task';
            app.Switch = uiswitch(app.Panel_3, 'slider');
            app.Switch.Items = {'Left', 'Right'};
            app.Switch.Value = 'Right';
            app.tableButton_6 = uibutton(app.Panel_3, 'push');
            app.tableButton_6.ButtonPushedFcn = createCallbackFcn(app, @tableButton_6Pushed, true);
            app.tableButton_6.Text = 'table';
            app.Blank = uitree(app.Panel_3);
            app.AddNewTaskPanel = uipanel(app.ControlGroupTab);
            app.AddNewTaskPanel.Title = 'Add New Task';
            app.AddButton_2 = uibutton(app.AddNewTaskPanel, 'push');
            app.AddButton_2.ButtonPushedFcn = createCallbackFcn(app, @AddButton_2Pushed, true);
            app.AddButton_2.Text = 'Add';
            app.EditField_3 = uieditfield(app.AddNewTaskPanel, 'text');
            app.TaskNameLabel = uilabel(app.AddNewTaskPanel);
            app.TaskNameLabel.Text = 'Task Name:';
            app.Switch_2 = uiswitch(app.AddNewTaskPanel, 'slider');
            app.Switch_2.Items = {'Left', 'Right'};
            app.Switch_2.Value = 'Right';
            app.RemoveTaskPanel = uipanel(app.ControlGroupTab);
            app.RemoveTaskPanel.Title = 'Remove Task';
            app.RemoveButton_2 = uibutton(app.RemoveTaskPanel, 'push');
            app.RemoveButton_2.ButtonPushedFcn = createCallbackFcn(app, @RemoveButton_2Pushed, true);
            app.RemoveButton_2.Text = 'Remove';
            app.TaskNameLabel_2 = uilabel(app.RemoveTaskPanel);
            app.TaskNameLabel_2.Text = 'Task Name:';
            app.DropDown_4 = uidropdown(app.RemoveTaskPanel);
            app.DropDown_4.Items = {'--'};
            app.DropDown_4.Value = '--';
            app.Table = uitab(app.TabGroup);
            app.Table.Title = 'Table';
            app.TabGroup3 = uitabgroup(app.Table);
            app.Subject1Tab_2 = uitab(app.TabGroup3);
            app.Subject1Tab_2.Title = 'Subject 1';
            app.UITable = uitable(app.Subject1Tab_2);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.saveButton_6 = uibutton(app.Subject1Tab_2, 'push');
            app.saveButton_6.ButtonPushedFcn = createCallbackFcn(app, @saveButton_6Pushed, true);
            app.saveButton_6.Text = 'save';
            app.Subject2Tab_2 = uitab(app.TabGroup3);
            app.Subject2Tab_2.Title = 'Subject 2';
            app.UITable_2 = uitable(app.Subject2Tab_2);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            app.saveButton_7 = uibutton(app.Subject2Tab_2, 'push');
            app.saveButton_7.ButtonPushedFcn = createCallbackFcn(app, @saveButton_7Pushed, true);
            app.saveButton_7.Text = 'save';
            app.Subject3Tab_2 = uitab(app.TabGroup3);
            app.Subject3Tab_2.Title = 'Subject 3';
            app.UITable_3 = uitable(app.Subject3Tab_2);
            app.UITable_3.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_3.RowName = {};
            app.saveButton_8 = uibutton(app.Subject3Tab_2, 'push');
            app.saveButton_8.ButtonPushedFcn = createCallbackFcn(app, @saveButton_8Pushed, true);
            app.saveButton_8.Text = 'save';
            app.Subject4Tab_2 = uitab(app.TabGroup3);
            app.Subject4Tab_2.Title = 'Subject 4';
            app.UITable_4 = uitable(app.Subject4Tab_2);
            app.UITable_4.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_4.RowName = {};
            app.saveButton_9 = uibutton(app.Subject4Tab_2, 'push');
            app.saveButton_9.ButtonPushedFcn = createCallbackFcn(app, @saveButton_9Pushed, true);
            app.saveButton_9.Text = 'save';
            app.Subject5Tab_2 = uitab(app.TabGroup3);
            app.Subject5Tab_2.Title = 'Subject 5';
            app.UITable_5 = uitable(app.Subject5Tab_2);
            app.UITable_5.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_5.RowName = {};
            app.saveButton_10 = uibutton(app.Subject5Tab_2, 'push');
            app.saveButton_10.ButtonPushedFcn = createCallbackFcn(app, @saveButton_10Pushed, true);
            app.saveButton_10.Text = 'save';
            app.CGTab = uitab(app.TabGroup3);
            app.CGTab.Title = 'CG';
            app.UITable_6 = uitable(app.CGTab);
            app.UITable_6.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_6.RowName = {};
            app.saveButton_11 = uibutton(app.CGTab, 'push');
            app.saveButton_11.ButtonPushedFcn = createCallbackFcn(app, @saveButton_11Pushed, true);
            app.saveButton_11.Text = 'save';
        end
    end

    methods (Access = public)

        % Construct app
        function app = MCAPA2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MCAPAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MCAPAUIFigure)
        end
    end
end